classdef RTSimulatorMA2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CrowdPropagationChannelPanel  matlab.ui.container.Panel
        channelFunctions              matlab.ui.control.Button
        RayTracingPanel               matlab.ui.container.Panel
        ShowRaysButton                matlab.ui.control.Button
        GenerateCrowdButton           matlab.ui.control.Button
        QuickaccesstopropertiesPanel  matlab.ui.container.Panel
        PeopledensityEditField        matlab.ui.control.NumericEditField
        PeopledensityEditFieldLabel   matlab.ui.control.Label
        FrequencyDropDown             matlab.ui.control.DropDown
        FrequencyDropDownLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Attributes of the app
        param                           % Properties window
        windowLimits                    % Window in which you can find the crowd
        xLength                         % windowLimits(2)-windowLimits(1)
        yLength                         % windowLimits(2)-windowLimits(1)
        peopleDensity                   % nb persons / m²
        peopleRadius                    % in m. People modeled as discs
        nbPersons=0;                    % default value=0 for the needs of the app
        nbWalls=0;
        people2DPosition
        emitters2DPosition
        receivers2DPosition
        walls
        f                               % carrier frequency
        lambda
        beta
        er                              % people relative permittivity
        sigma                           % people conductivity
        D=-2                            % dB. diffraction loss caused by people
        paths                           % multipath component structure
        PLE                             % path loss exponent
        PTX                             % emitting power
        GTX = 0;GRX = 0;LTX = 0;LRX = 0;
        minPower                        % min power detected by the user equipment
        maxDisplayedColor               % max power showed
        cd                              % colormap
        Z0 = 120*pi;
        e0 = 8.85412782e-12;
        u0 = 4*pi*1e-7;
        A                               % Equivalent surface for a vertical dipole
        mod;OFDM;OSF                    % OFDM properties
        allPaths
    end

    methods (Access = public)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  SETTERS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function setwindowLimits(app, windowLimits)
%             if isempty(app.windowLimits) || ~all(app.windowLimits == windowLimits)
                app.windowLimits = windowLimits;
                % Get window limits from the properties panel
%                 app.param.xminSpinner.Value = windowLimits(1);
%                 app.param.xmaxSpinner.Value = windowLimits(2);
%                 app.param.yminSpinner.Value = windowLimits(3);
%                 app.param.ymaxSpinner.Value = windowLimits(4);
                app.UIFigure.CurrentAxes.XLim = [windowLimits(1) windowLimits(2)];
                app.UIFigure.CurrentAxes.YLim = [windowLimits(3) windowLimits(4)];
                
                app.xLength = windowLimits(2) - windowLimits(1);
                app.yLength = windowLimits(4) - windowLimits(3);
%             end
        end

        function setPTX(app, PTX)
            if isempty(app.PTX) || PTX ~= app.PTX
                app.PTX = PTX;
                app.param.PTXEditField.Value = PTX;
%                 app.maxDisplayedColor = app.PTX - 60;
            end
        end

        function setPeopleDensity(app, density)
            if isempty(app.peopleDensity) || density ~= app.peopleDensity
                app.peopleDensity = density;
                app.PeopledensityEditField.Value = density;
                app.param.PeopledensityEditField.Value = density;
                % once the density set, set the number of persons
                app.setNbPersons(density * app.xLength ...
                    * app.yLength);
            end
        end
        
        function setNbWalls(app, n)
            if isempty(app.nbWalls) || n ~= app.nbWalls
                app.nbWalls = n;
            end            
        end

        function setNbPersons(app, n)
            if isempty(app.nbPersons) || n ~= app.nbPersons
                app.nbPersons = n;
                app.param.NbpersonsEditField.Value = n;
                % once the number of persons set, set the density if it is
                % not a PPP (since Poisson number of people for a given
                % density)
                if ~strcmp(app.param.CrowdModelDropDown.Value, "Poisson point process with repulsion")
                    app.setPeopleDensity(n / app.xLength ...
                        / app.yLength);
                end
            end
        end

        function setPeopleRadius(app, radius)
            if isempty(app.peopleRadius) || radius ~= app.peopleRadius
                app.peopleRadius = radius;
                app.param.PeopleradiusEditField.Value = radius;
            end
        end

        function setFrequency(app, freq)
            if isempty(app.f) || freq ~= app.f
                % Set frequency
                app.f = freq;
                app.FrequencyDropDown.Value = [num2str(app.f/1e9, 4) ' GHz'];
                app.param.FrequencyDropDown.Value = [num2str(app.f/1e9, 4) ' GHz'];
                % Set wavelength and wave vector
                app.lambda = 1/(app.f*sqrt(app.u0*app.e0));
                app.beta = 2*pi / app.lambda;
                % Update electric properties of the walls
                for i=1:numel(app.walls)
                    [gamma, Z] = app.setElecConst(app.walls(i));
                    app.walls(i).gamma = gamma;
                    app.walls(i).Z = Z;
                end
            end
        end

        function setPLE(app, PLE)
            app.PLE = PLE;
            app.param.PathlossexponentEditField.Value = PLE;
        end

        function setTXLocations(app, NTX, pos)
            % Place the antennas. If NTX or NRX is smaller or equal to 2,
            % the locations are pre-determined. Otherwise, the locations
            % are random
            if nargin == 2  
                if NTX == 1
                    app.emitters2DPosition = [app.windowLimits(1), app.windowLimits(3)];
                elseif NTX == 2
                    app.emitters2DPosition = [app.windowLimits(1)+7, app.windowLimits(3);app.windowLimits(2)-1, app.windowLimits(3)+4];
                else
                    app.emitters2DPosition = [app.xLength*rand(NTX, 1)+app.windowLimits(1), ...% shift to be in the window
                        app.yLength*rand(NTX, 1)+app.windowLimits(3)];
                end
            elseif nargin == 3
                app.emitters2DPosition = pos;
            end

            app.param.NTXSpinner.Value = NTX;
            app.param.upDateTX();
        end

        function setRXLocations(app, NRX, pos)
            % Place the antennas. If NTX or NRX is smaller or equal to 2,
            % the locations are pre-determined. Otherwise, the locations
            % are random
            if nargin == 2
                if NRX == 1
                    app.receivers2DPosition = [app.windowLimits(2), app.windowLimits(4)];
                elseif NRX == 2
                    app.receivers2DPosition = [app.windowLimits(1), app.windowLimits(4); ...
                        app.windowLimits(2)-3, (app.windowLimits(4)+1)/2];
                else
                    app.receivers2DPosition = [app.xLength*rand(NRX, 1)+app.windowLimits(1), ...% shift to be in the window
                        app.yLength*rand(NRX, 1)+app.windowLimits(3)];
                end
            elseif nargin == 3
                app.receivers2DPosition = pos;
            end

            app.param.NRXSpinner.Value = NRX;
            app.param.upDateRX();
        end
        
        function setWalls(app, walls)
            app.walls = walls;
            app.setNbWalls(numel(app.walls));
            for i = 1:app.nbWalls
                % Set electric properties
                [gamma, Z] = app.setElecConst(app.walls(i));
                app.walls(i).Z = Z;
                app.walls(i).gamma = gamma;
            end
            app.param.upDateWallTable();
        end

%         function setPeopleCharacteristics(app)
%             if app.f == 2.45e9
%                 app.er = 53;
%                 app.sigma = 1.5;
%                 app.D = 3;
%             elseif app.f == 5e9
%                 app.er = 50;
%                 app.sigma = 4.3;
%                 app.D = 6;
%             elseif app.f == 28e9
%                 app.er = 16;
%                 app.sigma = 25;
%                 app.D = 12;
%             elseif app.f == 60e9
%                 app.er = 8;
%                 app.sigma = 33;
%                 app.D = 15;
%             end
%         end

        function setWallsCharacteristics(app)
            % Change properties of the wall depending on the frequency
%             if app.f == 2.45e9
%             elseif app.f == 5e9
%             elseif app.f == 28e9
%             elseif app.f == 60e9
%             end
        end

        function [gamma, Z] = setElecConst(app, wall)
            % Set electric constants and properties depending on wall
            % parameters and frequency
            omega = 2*pi*app.f;
            skinCoef = sqrt(1+(wall.Conductivity/(omega*wall.Permittivity*app.e0))^2);
            alpha = sqrt(skinCoef-1);
            beta_m = sqrt(skinCoef+1);
            gamma = omega * sqrt(wall.Permeability*app.u0*wall.Permittivity*app.e0/2) * ...
                (alpha + beta_m*1i);
            Z = sqrt(wall.Permeability*app.u0/...
                (wall.Permittivity*app.e0-1i*wall.Conductivity/omega));
        end

        function setPeoplePosition(app, pos)
            app.people2DPosition = pos;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  PLOT FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function prepareMap(app)
            axis(app.UIAxes, 'equal');
            app.UIAxes.XLim = [app.windowLimits(1)-1, app.windowLimits(2)+1];
            app.UIAxes.YLim = [app.windowLimits(3)-1, app.windowLimits(4)+1];
%             app.cd = colormap(app.UIAxes, 'jet'); % choose jet style
%             app.cd(4,:) = 255; % last column is transparency
%             cb = colorbar(app.UIAxes);
%             values = linspace(app.minPower, app.maxDisplayedColor, 11); % scale limits
%             cb.TickLabels = cellstr(string(values));
%             cb.Label.String = 'Power (dBm)';
        end

        function plotUniquePerson(app, ind, faceColor, edgeColor)
            % Each person is plotted as a disc. The easiest way is to use
            % the matlab rectangle function with a [1,1] curvature
            rectangle(app.UIAxes, 'Position', ...
                [app.people2DPosition(ind,:)-app.peopleRadius, app.peopleRadius*2, app.peopleRadius*2],...
                'Curvature',[1,1], 'FaceColor', faceColor, 'EdgeColor', edgeColor);
        end

        function plotPeople(app)
            if ~isempty(app.people2DPosition)
                % Plot your crowd
                for i=1:app.nbPersons
                    app.plotUniquePerson(i, 'y', 'k');
                end
                % If a person in the crowd is selected, he/she will be
                % hightlighted in magenta
                if ~isempty(app.param.PeopleTree.SelectedNodes)
                    text = strsplit(app.param.PeopleTree.SelectedNodes.Text, ' ');
                    ind = str2double(text{2});
                    app.plotUniquePerson(ind, 'm', 'k');
                end
            end
        end

        function [x_coor, y_coor] = draw_rectangle(~, center, L, H, theta)
            % function to draw a rectangle: walls, coverage local areas
            R= ([cos(theta), -sin(theta); ...
                sin(theta), cos(theta)]);
            MAT1 = [-L/2, L/2; ...
                    -H/2, -H/2];
            MAT2 = [L/2, -L/2; ...
                    H/2, H/2];
            T1 = R*MAT1;
            T2 = R*MAT2;
            x_lower_left = center(1) + T1(1,1);
            x_lower_right = center(1) + T1(1,2);
            x_upper_right = center(1) + T2(1,1);
            x_upper_left = center(1) + T2(1,2);
            y_lower_left = center(2) + T1(2,1);
            y_lower_right = center(2) + T1(2,2);
            y_upper_right = center(2) + T2(2,1);
            y_upper_left = center(2) + T2(2,2);
            x_coor=[x_lower_left x_lower_right x_upper_right x_upper_left];
            y_coor=[y_lower_left y_lower_right y_upper_right y_upper_left];
        end
        
        function plotUniqueWall(app, ind, faceColor, ~)
            % Each wall is plotted as a disc. The easiest way is to use
            % the matlab rectangle function
            centerCoord = [(app.walls(ind).Extremity1(1)+app.walls(ind).Extremity2(1))/2, ...
                (app.walls(ind).Extremity1(2)+app.walls(ind).Extremity2(2))/2];
            [x_coor, y_coor] = app.draw_rectangle(centerCoord, app.walls(ind).Length, ...
                app.walls(ind).Width, app.walls(ind).thetan);
            fill(app.UIAxes, x_coor, y_coor, faceColor);
        end
        
        function plotReceivers(app)
            if ~isempty(app.receivers2DPosition)
                plot(app.UIAxes, app.receivers2DPosition(:,1), app.receivers2DPosition(:,2), 'x', 'Color', 'b');
                text(app.UIAxes, app.receivers2DPosition(:,1)+0.3, app.receivers2DPosition(:,2)+0.3, ...
                    'RX', "FontName", "Arial", "Color", 'b', "FontWeight", 'bold')
            end
        end

        function plotEmitters(app)
            if ~isempty(app.emitters2DPosition)
                plot(app.UIAxes, app.emitters2DPosition(:,1), app.emitters2DPosition(:,2), 'x', 'Color', 'r');
                text(app.UIAxes, app.emitters2DPosition(:,1)+0.3, app.emitters2DPosition(:,2)+0.3, ...
                    'TX', "FontName", "Arial", "Color", 'r', "FontWeight", 'bold')
            end
        end

        function plotWalls(app)
            if ~isempty(app.walls)
                % Plot your crowd
                for i=1:app.nbWalls
                    app.plotUniqueWall(i, 'b', 'b');
                end
                % If a wall is selected, it will be
                % hightlighted in magenta
                if ~isempty(app.param.WallTree.SelectedNodes)
                    text = strsplit(app.param.WallTree.SelectedNodes.Text, ' ');
                    ind = str2double(text{2});
                    app.plotUniqueWall(ind, 'm', 'm');
                end
            end
        end
        
        function plotRays(app)
            if ~isempty(app.paths)
                hold(app.UIAxes, 'on');
                ntx_colors = {'r','k','b','c','m','y','g'};
                nrx_styles = {'-',':','-.','--'};

                [Ntx, Nrx, Npeople] = size(app.paths);
                for i = 1:Ntx
                    for j = 1:Nrx
                        for p = 1:Npeople
                            plot(app.UIAxes, app.paths{i,j,p}.intersectPt(:,1), app.paths{i,j,p}.intersectPt(:,2), ...
                            'Color', ntx_colors{i}, 'LineStyle', nrx_styles{j});
                        end
                    end
                end

            end
        end

        function plotConfiguration(app)
            cla(app.UIAxes);
            if ~isempty(app.walls)
                app.plotWalls();
                hold(app.UIAxes, 'on');
            end
            if ~isempty(app.people2DPosition)
                app.plotPeople();
                hold(app.UIAxes, 'on');
            end
            if ~isempty(app.emitters2DPosition)
                app.plotEmitters();
                hold(app.UIAxes, 'on');
            end
            if ~isempty(app.receivers2DPosition)
                app.plotReceivers();
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  FUNCTIONS TO INITIALIZE COMPUTATIONS: Crowd models
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function newPosFound = addNewPersonWithoutCrash(app, currentPos, newPos)
            dist = (currentPos - newPos).^2;
            dist = sum(dist, 2);
            % Check if the new person does not intersect anyone
            newPosFound = dist > (app.peopleRadius*2)^2;
        end

        function generatePeople(app, showWaitBar)
            % Generate the crowd depending on the density and the spatial
            % distribution asked

            app.paths = {}; % Re-initialize MPC vector to avoid errors


            if strcmp(app.param.CrowdModelDropDown.Value, "Poisson point process with repulsion")
                % Generate a Poisson random number for the PPP model (N is
                % a random variable)
                app.setNbPersons(poissrnd(app.peopleDensity * ...
                    app.xLength * app.yLength));
            else
                % Set the number of people (N is a number)
                app.setNbPersons(app.peopleDensity * ...
                    app.xLength * app.yLength);
            end

            if app.nbPersons > 0 && ~strcmp(app.param.CrowdModelDropDown.Value, "Regular grid")
                % For PPP and BPP models
                if showWaitBar
                    wb = waitbar(0, 'Generation of the crowd...');
                end

                % Start with one random position
                positions = [rand(1)*app.xLength+app.windowLimits(1),rand(1)*app.yLength+app.windowLimits(3)];
                ind = 1;
                numit = 0;
                while ind < app.nbPersons && numit < app.nbPersons*20
                    % Generate a new position
                    newPosition = [rand(1)*app.xLength+app.windowLimits(1),rand(1)*app.yLength+app.windowLimits(3)];
                    % Test this new position and check if the new person
                    % does not intersect anyone.
                    newPosFound = app.addNewPersonWithoutCrash(positions, newPosition);
                    if newPosFound
                        positions = [positions; newPosition];
                        ind = ind + 1;
                        if showWaitBar
                            waitbar(ind/app.nbPersons, wb, ...
                                ['Generation of the crowd... - ' num2str(ind/app.nbPersons*100, 4) '%']);
                        end
                    end
                    numit = numit + 1;
                end
                app.setNbPersons(ind); % re-set in case of nobody can be placed anymore
                app.setPeoplePosition(positions);

                if showWaitBar
                    app.param.upDateCrowdTable(); % update the crowd table in the properties window
                    waitbar(1, wb, 'Finished');
                    close(wb);
                end
            elseif strcmp(app.param.CrowdModelDropDown.Value, "Regular grid")
                % Be careful: this code is not perfect
                if showWaitBar
                    wb = waitbar(0, 'Generation of the crowd...');
                end
                maxNb = (floor(app.xLength/(2*app.peopleRadius))+1) ...
                    * (floor(app.yLength/(2*app.peopleRadius))+1); % max number of people that can be placed
                if app.nbPersons > maxNb
                    app.setNbPersons(maxNb); % in case of nobody can be placed anymore
                end
                % Create 2 regular vectors x and y
                xmesh = (app.windowLimits(1):app.xLength/ceil(sqrt(app.nbPersons*app.xLength/app.yLength)):app.windowLimits(2))';
                ymesh = (app.windowLimits(3):app.yLength/ceil(sqrt(app.nbPersons/app.xLength*app.yLength)):app.windowLimits(4))';
                [xmesh, ymesh] = meshgrid(xmesh, ymesh); % transform into a meshgrid
                xmesh = reshape(xmesh, numel(xmesh), 1); % reshape the mesh positions into a vector
                ymesh = reshape(ymesh, numel(ymesh), 1);
                app.setPeoplePosition([xmesh(1:app.nbPersons), ymesh(1:app.nbPersons)]);

                if showWaitBar
                    app.param.upDateCrowdTable(); % update the crowd table in the properties window
                    waitbar(1, wb, 'Finished');
                    close(wb);
                end
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  COMPUTATION FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [n, nbScatterers] = computeNormalVectorAndNbScatterers(app, startPoint, endPoint, people)
            % Compute the unitary vector n between startPoint and endPoint.
            % Then, for each person in people, look if he/she is not
            % crossed. The number of people crossed in given in
            % nbScatterers.

            P12 = endPoint - startPoint; % Vector from startPoint to endPoint
            n = P12 / norm(P12);  % Normalized vector in direction of the ray

            % Seach for intersection with people
            if ~isempty(people)
                startToPerson = people - startPoint; % Line from startPoint to the people center
                v = abs(n(1) * startToPerson(:,2) - n(2) * startToPerson(:,1)); % Norm of the 2D cross-product
                doIntersect = (v <= app.peopleRadius); % Shortest distance between ray and center of the disc
                nbScatterers = sum(doIntersect);
            else
                nbScatterers = 0;
            end
        end

        function [P, d] = findIntersectionOnWall(~,TX, RX, wall)
            % Distance traveled 
            d = RX-TX;
            % Steering vector
            vecu = [wall.nx, wall.ny];
            
            if abs(d*[vecu(2); -vecu(1)]) > 1e-15 % non-zero scalar product
                % Intersection point
                t = (d(2) * (TX(1)-wall.Extremity1(1)) - ...
                    d(1) * (TX(2)-wall.Extremity1(2))) / ...
                    (vecu(1)*d(2)-vecu(2)*d(1));
                
                if t > 0 && t < wall.Length % if intersection on the wall
                    P = wall.Extremity1 + t * vecu;
                else
                    P = []; % no intersection
                end
            else
                P = []; % no intersection
            end
        end
            
        function Gm = computeReflectionCoefficient(app, wall, d)
            % Reflection coefficient depending on wall properties and
            % frequency
            res = d;
            G = @(Z1, Z2, cosi, cost) (Z2*cosi-Z1*cost)./(Z2*cosi+Z1*cost); % transmission coefficient for an infinite wall
            sini = @(cosi) sqrt(1-cosi^2);
            sint = @(e1, e2, sini) (sini*sqrt(e1/e2));
            cost = @(sint) sqrt(1-sint^2);
            width = @(l, cost) l/cost;
            Gm = @(G, gamma_m, beta, s, sint, sini, f) (G - (1-G^2) .* G.*exp(-2*gamma_m*s).*exp(1i*beta*2*s.*sint.*sini) ...
                ./(1-G.^2.*exp(-2*gamma_m*s).*exp(1i*beta*2*s.*sint.*sini))); % reflection coefficient for a wall

            % Steering vector
            ci = abs(res * [wall.ny; -wall.nx] / norm(res));
            %ci = abs([d(1,1,1,1), d(1,1,1,2)] * [wall.ny; -wall.nx] / norm([d(1,1,1,1), d(1,1,1,2)]));
            si = sini(ci);
            st = sint(1, wall.Permittivity, si);
            ct = cost(st);
            Gm = Gm(G(app.Z0, wall.Z, ci, ct), wall.gamma, ...
                app.beta, width(wall.Width, ct), st, si, app.f);
        end
        
        function Tm = computeTransmissionCoefficient(app, wall, d)
            % Transmission coefficient depending on wall properties and
            % frequency
            G = @(Z1, Z2, cosi, cost) (Z2*cosi-Z1*cost)./(Z2*cosi+Z1*cost); % transmission coefficient for an infinite wall
            sini = @(cosi) sqrt(1-cosi^2);
            sint = @(e1, e2, sini) (sini*sqrt(e1/e2));
            cost = @(sint) sqrt(1-sint^2);
            width = @(l, cost) l/cost;
            Tm = @(G, gamma_m, beta, s, sint, sini, f) ((1-G.^2).*exp(-gamma_m*s) ...
                ./(1-G.^2.*exp(-2*gamma_m*s).*exp(1i*beta*2*s.*sint.*sini))); % transmission coefficient for a wall

            % Steering vector
            ci = abs(d * [wall.ny; -wall.nx] / norm(d));
            si = sini(ci);
            st = sint(1, wall.Permittivity, si);
            ct = cost(st);
            Tm = Tm(G(app.Z0, wall.Z, ci, ct), wall.gamma, ...
                app.beta, width(wall.Width, ct), st, si, app.f);            
        end        

        function computeRays(app)
            % Compute the power and distance for each MPC. Based on the
            % vectorial method.
%             app.paths = ; % Initialize MPC vector
            

%             % EM characteristics
%             ec = app.e0*app.er - 1i * app.sigma/(2*pi*app.f);
%             Z2 = sqrt(app.u0/ec);

            % RX and TX position
            TXpos = app.emitters2DPosition;
            RXpos = app.receivers2DPosition;

            % If you don't want people
            if isempty(app.people2DPosition)
                app.setNbPersons(0);
            end

            % If you forgot to generate people
            if isempty(TXpos) || isempty(RXpos)
                app.OutputMessagesTextArea.Value = ['No TX and/or RX here.' ...
                    'Place first your antennas.'];
                return;
            end

            app = getRays(app, TXpos, RXpos);
            chInfo.TXpos = app.emitters2DPosition;
            chInfo.RXpos = app.receivers2DPosition;
            chInfo.HUMpos = app.people2DPosition;
            chInfo.paths = app.paths;
            chInfo.walls = app.walls;
            assignin('base', 'rayInfo', chInfo);
           
        end

        function setConfigurationSimulation(app)
            app.generatePeople(true);
            app.plotConfiguration();
        end
       
        function choice = defaultLocationMessage(app, msg, nTX, nRX)
            % Show this message when the number of antennas displayed is
            % not correct.
            title = 'Simulation error';
            selection = questdlg(msg, title, 'Yes', 'No', 'Yes');
            switch selection
                case 'Yes'
                    app.setTXLocations(nTX);
                    app.setRXLocations(nRX);
                    app.plotConfiguration();
                    choice = 1;
                case 'No'
                    choice = 0;
            end
        end

        function choice = noRayComputedMessage(app)
            % Show this message when no ray is already computed.
            title = 'Missing rays';
            msg = ['People are generated but it seems that no ray was computed. ' ...
                'Do you want to compute the rays before going further?'];
            selection = questdlg(msg, title, 'Yes', 'No', 'Yes');
            switch selection
                case 'Yes'
                    app.computeRays();
                    app.plotConfiguration();
                    app.plotRays();
                    choice = 1;
                case 'No'
                    choice = 0;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('.\functions\');
            app.param = RTSimulatorParameters(app);

            set(groot,'defaulttextinterpreter','latex');
            set(groot,'defaultAxesTickLabelInterpreter','latex');
            set(groot,'defaultLegendInterpreter','latex');
            set(0,'DefaultLineLineWidth',1);
            set(0,'defaultAxesFontSize',    13); % high font size for plots

            % Initize some of the attributes
            app.Z0 = sqrt(app.u0/app.e0);
            app.setwindowLimits([1, 19, 1, 19]);
            app.setPLE(2);
            app.setPeopleDensity(0.5);
            app.setPeopleRadius(0.15);
            app.setPTX(10); %dB
            app.setFrequency(2.45e9);
            app.setWallsCharacteristics();
            app.setTXLocations(1);  % pre-defined location of TX antennas
            app.setRXLocations(1);  % pre-defined location of RX antennas
            app.A = app.Z0 /(4*pi^2*73*app.f^2*app.e0*app.u0); % Equivalent surface for a vertical dipole with Ra = 73ohm

            % Initialize the map and plot emitters and receivers
            app.prepareMap();
            app.plotConfiguration();

            % Prepare the folders needed to save and load data and figures.
            % Attention: not case-sensitive
            if exist('data', 'dir') ~= 7
                mkdir('data\');
            end
            if exist('Figures', 'dir') ~= 7
                mkdir('Figures\');
            end
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            app.setFrequency(str2double(regexp(app.FrequencyDropDown.Value,'\d+\.?\d*', 'match'))*1e9);
            app.setPeopleCharacteristics();
        end

        % Value changed function: PeopledensityEditField
        function PeopledensityEditFieldValueChanged(app, event)
            app.setPeopleDensity(app.PeopledensityEditField.Value);
        end

        % Callback function
        function NarrowbandattenuationstatisticsButtonPushed(app, event)
            if ~isempty(app.emitters2DPosition) && ~isempty(app.receivers2DPosition)
                app.plotConfiguration();
                app.computeAttenuationStatistics();
            else
                msg = ['Not possible to calculate the ' ...
                    'narrowband attenuation statistics. The number of ' ...
                    'emitters and/or transmitters is not below 1.'];
                if app.defaultLocationMessage(msg, 2, 2)
                    app.computeAttenuationStatistics();
                else
                    app.OutputMessagesTextArea.Value = msg;
                end
            end
        end

        % Button pushed function: GenerateCrowdButton
        function GenerateCrowdButtonPushed(app, event)
            app.generatePeople(true);
%             cla(app.UIAxes);
%             app.plotPeople();
            app.setTXLocations(app.param.NTXSpinner.Value, app.emitters2DPosition);
            app.setRXLocations(app.param.NRXSpinner.Value, app.receivers2DPosition);
%             app.plotEmitters();
%             app.plotReceivers();
            app.plotConfiguration();
        end

        % Button pushed function: ShowRaysButton
        function ShowRaysButtonPushed(app, event)
            if ~isempty(app.emitters2DPosition) && ~isempty(app.receivers2DPosition)
                app.computeRays();
                app.plotConfiguration();
                app.plotRays();
            else
                msg = ['Not possible to calculate the ' ...
                    'rays. You forgot to add emitting or ' ...
                    'receiving antennas'];
                if app.defaultLocationMessage(msg, 2, 2)
                    app.computeRays();
                    app.plotConfiguration();
                    app.plotRays();
                else
                    app.OutputMessagesTextArea.Value = msg;
                end
            end
        end

        % Button pushed function: channelFunctions
        function channelFunctionsPushed(app, event)
            if size(app.emitters2DPosition, 1)==1 && size(app.receivers2DPosition, 1)==1
                if isempty(app.paths) && ~isempty(app.people2DPosition)
                    app.noRayComputedMessage();
                end
                % Set OFDM parameters
            else
                msg = ['Not possible to calculate the ' ...
                    'channel impulse response and the channel transfer ' ...
                    'function. The number of emitters and/or transmitters ' ...
                    'is not equal to 1.'];
                if app.defaultLocationMessage(msg, 1, 1)
                    if isempty(app.paths) && ~isempty(app.people2DPosition)
                        app.noRayComputedMessage();
                    end
                    % Set OFDM parameters
                else
                    app.OutputMessagesTextArea.Value = msg;
                end
            end
        end

        % Callback function
        function delayCoherenceBWPushed(app, event)
            if size(app.emitters2DPosition, 1)==1 && size(app.receivers2DPosition, 1)==1
                if isempty(app.paths) && ~isempty(app.people2DPosition)
                    app.noRayComputedMessage();
                end
                % Set OFDM parameters
                app.setOFDMParameters();
                app.computeDelaySpreadCoherenceBW();
            else
                msg = ['Not possible to calculate the delay spread and ' ...
                    'the coherence bandwidth. The number of emitters and' ...
                    '/or transmitters is not equal to 1.'];
                if app.defaultLocationMessage(msg, 1, 1)
                    if isempty(app.paths) && ~isempty(app.people2DPosition)
                        app.noRayComputedMessage();
                    end
                    % Set OFDM parameters
                else
                    app.OutputMessagesTextArea.Value = msg;
                end
            end
        end

        % Callback function
        function channelEstimationButtonPushed(app, event)
            if size(app.emitters2DPosition, 1)==1 && size(app.receivers2DPosition, 1)==1
                if isempty(app.paths) && ~isempty(app.people2DPosition)
                    app.noRayComputedMessage();
                end
                % Set OFDM parameters
            else
                msg = ['Not possible to calculate the ' ...
                    'channel impulse response and the channel transfer ' ...
                    'function. The number of emitters and/or transmitters ' ...
                    'is not equal to 1.'];
                if app.defaultLocationMessage(msg, 1, 1)
                    if isempty(app.paths) && ~isempty(app.people2DPosition)
                        app.noRayComputedMessage();
                    end
                    % Set OFDM parameters
                    app.setOFDMParameters();
                    app.estimateChannel();
                else
                    app.OutputMessagesTextArea.Value = msg;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.param);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB MA2 Project SImulator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X [m]', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y [m]', 'Interpreter', 'latex')
            app.QuickaccesstopropertiesPanel = uipanel(app.UIFigure);
            app.QuickaccesstopropertiesPanel.Title = 'Quick access to properties';
            app.FrequencyDropDownLabel = uilabel(app.QuickaccesstopropertiesPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.QuickaccesstopropertiesPanel);
            app.FrequencyDropDown.Items = {'2.45 GHz', '5 GHz', '28 GHz', '60 GHz'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '2.45 GHz';
            app.PeopledensityEditFieldLabel = uilabel(app.QuickaccesstopropertiesPanel);
            app.PeopledensityEditFieldLabel.Text = 'People density';
            app.PeopledensityEditField = uieditfield(app.QuickaccesstopropertiesPanel, 'numeric');
            app.PeopledensityEditField.ValueDisplayFormat = '%11.4g persons/m²';
            app.PeopledensityEditField.ValueChangedFcn = createCallbackFcn(app, @PeopledensityEditFieldValueChanged, true);
            app.RayTracingPanel = uipanel(app.UIFigure);
            app.RayTracingPanel.Title = 'Ray-Tracing';
            app.GenerateCrowdButton = uibutton(app.RayTracingPanel, 'push');
            app.GenerateCrowdButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCrowdButtonPushed, true);
            app.GenerateCrowdButton.Text = 'Generate Crowd';
            app.ShowRaysButton = uibutton(app.RayTracingPanel, 'push');
            app.ShowRaysButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRaysButtonPushed, true);
            app.ShowRaysButton.Text = 'Show Rays';
            app.CrowdPropagationChannelPanel = uipanel(app.UIFigure);
            app.CrowdPropagationChannelPanel.Title = 'Crowd Propagation Channel';
            app.channelFunctions = uibutton(app.CrowdPropagationChannelPanel, 'push');
            app.channelFunctions.ButtonPushedFcn = createCallbackFcn(app, @channelFunctionsPushed, true);
            app.channelFunctions.Text = 'Channel impulse response and transfer function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RTSimulatorMA2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end