classdef RTSimulatorParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatorParametersUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AntennaTab                      matlab.ui.container.Tab
        NumberofantennasandcarrierfrequencyPanel  matlab.ui.container.Panel
        FrequencyDropDownLabel          matlab.ui.control.Label
        FrequencyDropDown               matlab.ui.control.DropDown
        PTXEditFieldLabel               matlab.ui.control.Label
        PTXEditField                    matlab.ui.control.NumericEditField
        NTXSpinnerLabel                 matlab.ui.control.Label
        NTXSpinner                      matlab.ui.control.Spinner
        NRXSpinnerLabel                 matlab.ui.control.Label
        NRXSpinner                      matlab.ui.control.Spinner
        AntennaspropertiesPanel         matlab.ui.container.Panel
        AntennaTree                     matlab.ui.container.Tree
        EmittersNode                    matlab.ui.container.TreeNode
        ReceiversNode                   matlab.ui.container.TreeNode
        xSpinnerLabel                   matlab.ui.control.Label
        xSpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        ySpinner                        matlab.ui.control.Spinner
        LoadlocationsButton_2           matlab.ui.control.Button
        SavelocationsButton_2           matlab.ui.control.Button
        AddemitterButton                matlab.ui.control.Button
        AddreceiverButton               matlab.ui.control.Button
        RemovethisantennaButton         matlab.ui.control.Button
        CrowdTab                        matlab.ui.container.Tab
        PeopleradiusEditFieldLabel      matlab.ui.control.Label
        PeopleradiusEditField           matlab.ui.control.NumericEditField
        PeopledensityEditFieldLabel     matlab.ui.control.Label
        PeopledensityEditField          matlab.ui.control.NumericEditField
        PeoplepositionsPanel            matlab.ui.container.Panel
        PeopleTree                      matlab.ui.container.Tree
        xSpinner_2Label                 matlab.ui.control.Label
        xSpinner_2                      matlab.ui.control.Spinner
        ySpinner_2Label                 matlab.ui.control.Label
        ySpinner_2                      matlab.ui.control.Spinner
        LoadlocationsButton             matlab.ui.control.Button
        SavelocationsButton             matlab.ui.control.Button
        AddapersonButton                matlab.ui.control.Button
        RemovethispersonButton          matlab.ui.control.Button
        NbpersonsEditFieldLabel         matlab.ui.control.Label
        NbpersonsEditField              matlab.ui.control.NumericEditField
        CrowdModelDropDownLabel         matlab.ui.control.Label
        CrowdModelDropDown              matlab.ui.control.DropDown
        PropagationTab                  matlab.ui.container.Tab
        PropagationlawsPanel            matlab.ui.container.Panel
        DirectpathButtonGroup           matlab.ui.container.ButtonGroup
        AllowDirectPath                 matlab.ui.control.RadioButton
        CancelDirectPath                matlab.ui.control.RadioButton
        PathlossexponentEditFieldLabel  matlab.ui.control.Label
        PathlossexponentEditField       matlab.ui.control.NumericEditField
        ReflectiononpeopleButtonGroup   matlab.ui.container.ButtonGroup
        FriislawwithPLE                 matlab.ui.control.RadioButton
        Bistaticradarequation           matlab.ui.control.RadioButton
        WindowTab                       matlab.ui.container.Tab
        CrowdlimitsPanel                matlab.ui.container.Panel
        ApplychangesButton              matlab.ui.control.Button
        xminSpinnerLabel                matlab.ui.control.Label
        xminSpinner                     matlab.ui.control.Spinner
        xmaxSpinnerLabel                matlab.ui.control.Label
        xmaxSpinner                     matlab.ui.control.Spinner
        yminSpinnerLabel                matlab.ui.control.Label
        yminSpinner                     matlab.ui.control.Spinner
        ymaxSpinnerLabel                matlab.ui.control.Label
        ymaxSpinner                     matlab.ui.control.Spinner
        OFDMpropertiesTab               matlab.ui.container.Tab
        ModulationDropDownLabel         matlab.ui.control.Label
        ModulationDropDown              matlab.ui.control.DropDown
        BandwidthSpinnerLabel           matlab.ui.control.Label
        BandwidthSpinner                matlab.ui.control.Spinner
        NumberofsubcarriersEditFieldLabel  matlab.ui.control.Label
        NumberofsubcarriersEditField    matlab.ui.control.NumericEditField
        ChannelmaxlengthEditFieldLabel  matlab.ui.control.Label
        ChannelmaxlengthEditField       matlab.ui.control.NumericEditField
        OSFEditFieldLabel               matlab.ui.control.Label
        OSFEditField                    matlab.ui.control.NumericEditField
        WallsTab                        matlab.ui.container.Tab
        WalllistPanel                   matlab.ui.container.Panel
        WallTree                        matlab.ui.container.Tree
        LoadWallLocations               matlab.ui.control.Button
        SaveWallLocations               matlab.ui.control.Button
        AddWallButton                   matlab.ui.control.Button
        RemoveWallButton                matlab.ui.control.Button
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        Extremity1Panel                 matlab.ui.container.Panel
        xSpinner_3Label                 matlab.ui.control.Label
        xw1                             matlab.ui.control.Spinner
        ySpinner_3Label                 matlab.ui.control.Label
        yw1                             matlab.ui.control.Spinner
        Extremity2Panel                 matlab.ui.container.Panel
        xSpinner_4Label                 matlab.ui.control.Label
        xw2                             matlab.ui.control.Spinner
        ySpinner_4Label                 matlab.ui.control.Label
        yw2                             matlab.ui.control.Spinner
        SteeringvectorPanel             matlab.ui.container.Panel
        nxLabel                         matlab.ui.control.Label
        nx                              matlab.ui.control.Spinner
        nyLabel                         matlab.ui.control.Label
        ny                              matlab.ui.control.Spinner
        AngleSpinnerLabel               matlab.ui.control.Label
        thetan                          matlab.ui.control.Spinner
        WidthPanel                      matlab.ui.container.Panel
        WidthSpinner                    matlab.ui.control.Spinner
        ElectricconstantPanel           matlab.ui.container.Panel
        PermittivitySpinner             matlab.ui.control.Spinner
        ConductivitySpinner             matlab.ui.control.Spinner
        RelativepermittivityLabel       matlab.ui.control.Label
        RelativepermeabilityLabel       matlab.ui.control.Label
        ConductivityLabel               matlab.ui.control.Label
        PermeabilitySpinner             matlab.ui.control.Spinner
        LengthPanel                     matlab.ui.container.Panel
        LengthSpinner                   matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        RTSimulator % Description
    end
    
    methods (Access = public)
        
        function upDateTX(app)
            app.EmittersNode.Children.delete;
            for i = 1:size(app.RTSimulator.emitters2DPosition, 1)
                uitreenode(app.EmittersNode, 'Text', ['TX ' num2str(i)], ...
                    "NodeData", app.RTSimulator.emitters2DPosition(i,:));
            end
        end
        
        function upDateRX(app)
            app.ReceiversNode.Children.delete;
            for i = 1:size(app.RTSimulator.receivers2DPosition, 1)
                uitreenode(app.ReceiversNode, 'Text', ['RX ' num2str(i)], ...
                    "NodeData", app.RTSimulator.receivers2DPosition(i,:));
            end
        end
        
        function upDateCrowdTable(app)
            app.PeopleTree.Children.delete;
            for i=1:size(app.RTSimulator.people2DPosition, 1)
                uitreenode(app.PeopleTree, 'Text', ['Person ' num2str(i)], ...
                    "NodeData", app.RTSimulator.people2DPosition(i,:));
            end
        end
        
        function upDateWallTable(app)
            app.WallTree.Children.delete;
            for i=1:app.RTSimulator.nbWalls
                uitreenode(app.WallTree, 'Text', ['Wall ' num2str(i)], ...
                    "NodeData", app.RTSimulator.walls(i));
            end
        end
        
        function plotConfiguration(app)
            app.RTSimulator.plotConfiguration();
        end
        
        function updateWallSpinnersFromNodeData(app)
            app.xw1.Value = app.WallTree.SelectedNodes.NodeData.Extremity1(1);
            app.yw1.Value = app.WallTree.SelectedNodes.NodeData.Extremity1(2);
            app.xw2.Value = app.WallTree.SelectedNodes.NodeData.Extremity2(1);
            app.yw2.Value = app.WallTree.SelectedNodes.NodeData.Extremity2(2);
            app.WidthSpinner.Value = app.WallTree.SelectedNodes.NodeData.Width;
            app.LengthSpinner.Value = app.WallTree.SelectedNodes.NodeData.Length;
            app.nx.Value = app.WallTree.SelectedNodes.NodeData.nx;
            app.ny.Value = app.WallTree.SelectedNodes.NodeData.ny;
            app.thetan.Value = app.WallTree.SelectedNodes.NodeData.thetan*180/pi;
            app.ConductivitySpinner.Value = app.WallTree.SelectedNodes.NodeData.Conductivity;
            app.PermittivitySpinner.Value = app.WallTree.SelectedNodes.NodeData.Permittivity;
            app.PermeabilitySpinner.Value = app.WallTree.SelectedNodes.NodeData.Permeability;
        end
        
        function updateWallNodeDataFromSpinners(app)
            app.WallTree.SelectedNodes.NodeData.Extremity1(1) = app.xw1.Value;
            app.WallTree.SelectedNodes.NodeData.Extremity1(2) = app.yw1.Value;
            app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.xw2.Value;
            app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.yw2.Value;
            app.WallTree.SelectedNodes.NodeData.Width = app.WidthSpinner.Value;
            app.WallTree.SelectedNodes.NodeData.Length = app.LengthSpinner.Value;
            app.WallTree.SelectedNodes.NodeData.nx = app.nx.Value;
            app.WallTree.SelectedNodes.NodeData.ny = app.ny.Value;
            app.WallTree.SelectedNodes.NodeData.thetan = app.thetan.Value;
            app.WallTree.SelectedNodes.NodeData.Conductivity = app.ConductivitySpinner.Value;
            app.WallTree.SelectedNodes.NodeData.Permittivity = app.PermittivitySpinner.Value;
            app.WallTree.SelectedNodes.NodeData.Permeability = app.PermeabilitySpinner.Value;
        end
        
        function updateWallStructFromNodeData(app, ind)
            app.RTSimulator.walls(ind) = app.WallTree.SelectedNodes.NodeData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RTSimulator)
            app.RTSimulator = RTSimulator;
        end

        % Close request function: SimulatorParametersUIFigure
        function SimulatorParametersUIFigureCloseRequest(app, event)
            
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            app.RTSimulator.setFrequency(str2double(regexp(app.FrequencyDropDown.Value,'\d+\.?\d*', 'match'))*1e9);
            app.RTSimulator.setPeopleCharacteristics();
            app.RTSimulator.setFrequency(newFreq);
            app.RTSimulator.setWallsCharacteristics();
        end

        % Value changed function: PTXEditField
        function PTXEditFieldValueChanged(app, event)
            app.RTSimulator.setPTX(app.PTXEditField.Value);
        end

        % Value changed function: PeopleradiusEditField
        function PeopleradiusEditFieldValueChanged(app, event)
            app.RTSimulator.setPeopleRadius(app.PeopleradiusEditField.Value);
        end

        % Value changed function: PeopledensityEditField
        function PeopledensityEditFieldValueChanged(app, event)
            app.RTSimulator.setPeopleDensity(app.PeopledensityEditField.Value);
        end

        % Value changed function: NbpersonsEditField
        function NbpersonsEditFieldValueChanged(app, event)
            app.RTSimulator.setNbPersons(app.NbpersonsEditField.Value);
        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            newWindowLimits = [app.xminSpinner.Value, app.xmaxSpinner.Value,...
                app.yminSpinner.Value, app.ymaxSpinner.Value];
            app.RTSimulator.setwindowLimits(newWindowLimits);
        end

        % Selection changed function: AntennaTree
        function AntennaTreeSelectionChanged(app, event)
            if ~strcmp(app.AntennaTree.SelectedNodes.Text, 'Emitters') && ...
                    ~strcmp(app.AntennaTree.SelectedNodes.Text, 'Receivers') 
                app.xSpinner.Value = app.AntennaTree.SelectedNodes.NodeData(1);
                app.ySpinner.Value = app.AntennaTree.SelectedNodes.NodeData(2);
            end
        end

        % Selection changed function: PeopleTree
        function PeopleTreeSelectionChanged(app, event)
            app.xSpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(1);
            app.ySpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(2); 
            app.plotConfiguration();
        end

        % Value changed function: PathlossexponentEditField
        function PathlossexponentEditFieldValueChanged(app, event)
            app.RTSimulator.setPLE(app.PathlossexponentEditField.Value);
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(app, event)
            if ~isempty(app.AntennaTree.SelectedNodes)
                app.AntennaTree.SelectedNodes.NodeData(1) = app.xSpinner.Value;
                text = strsplit(app.AntennaTree.SelectedNodes.Text, ' ');
                type = text{1};ind = str2double(text{2});
                if strcmp(type, 'TX')
                    app.RTSimulator.emitters2DPosition(ind,1) = app.xSpinner.Value;
                elseif strcmp(type, 'RX')
                    app.RTSimulator.receivers2DPosition(ind,1) = app.xSpinner.Value;
                end
                app.plotConfiguration();
            end
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)
            if ~isempty(app.AntennaTree.SelectedNodes)
                app.AntennaTree.SelectedNodes.NodeData(2) = app.ySpinner.Value;
                text = strsplit(app.AntennaTree.SelectedNodes.Text, ' ');
                type = text{1};ind = str2double(text{2});
                if strcmp(type, 'TX')
                    app.RTSimulator.emitters2DPosition(ind,2) = app.ySpinner.Value;
                elseif strcmp(type, 'RX')
                    app.RTSimulator.receivers2DPosition(ind,2) = app.ySpinner.Value;
                end
                app.plotConfiguration();
            end  
        end

        % Value changed function: xSpinner_2
        function xSpinner_2ValueChanged(app, event)
            if ~isempty(app.PeopleTree.SelectedNodes)
                app.PeopleTree.SelectedNodes.NodeData(1) = app.xSpinner_2.Value;
                text = strsplit(app.PeopleTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                app.RTSimulator.people2DPosition(ind, 1) = app.xSpinner_2.Value;
                app.plotConfiguration();
            end   
        end

        % Value changed function: ySpinner_2
        function ySpinner_2ValueChanged(app, event)
            if ~isempty(app.PeopleTree.SelectedNodes)
                app.PeopleTree.SelectedNodes.NodeData(2) = app.ySpinner_2.Value;
                text = strsplit(app.PeopleTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                app.RTSimulator.people2DPosition(ind, 2) = app.ySpinner_2.Value;
                app.plotConfiguration();
            end  
        end

        % Value changed function: NTXSpinner
        function NTXSpinnerValueChanged(app, event)
            app.RTSimulator.setTXLocations(app.NTXSpinner.Value);
            app.RTSimulator.plotConfiguration();
        end

        % Value changed function: NRXSpinner
        function NRXSpinnerValueChanged(app, event)
            app.RTSimulator.setRXLocations(app.NRXSpinner.Value);
            app.RTSimulator.plotConfiguration();
        end

        % Button pushed function: LoadlocationsButton
        function LoadlocationsButtonPushed(app, event)
            % Load the crowd positions
            % Start by opening a window to select the file
            [file, path] = uigetfile('*people*.csv', 'Select a file', ...
                'data\');
            if isequal(file,0) % cancel option
               app.RTSimulator.OutputMessagesTextArea.Value = 'User selected Cancel.';
            else % file selected
                fileName = fullfile(path, file);
%                 app.RTSimulator.OutputMessagesTextArea.Value = ['User selected ', fileName];
                % get and set the people positions from the file
                pos = load(fileName);
                app.RTSimulator.setPeoplePosition(pos);
                app.RTSimulator.setNbPersons(size(pos, 1));
                app.upDateCrowdTable();
                % find the information about the crowd from the file name
%                 fileNameSplit = strsplit(fileName, {'_', '.csv'});
%                 switch fileNameSplit{2}
%                     case 'RG'
%                         app.CrowdModelDropDown.Value = 'Regular grid';
%                     case 'BPP'
%                         app.CrowdModelDropDown.Value = 'Binomial point process with repulsion';
%                     case 'PPP'
%                         app.CrowdModelDropDown.Value = 'Poisson point process with repulsion';
%                         app.RTSimulator.setNbPersons(size(pos, 1));
%                 end 
%                 app.RTSimulator.setPeopleDensity(str2double(fileNameSplit{4}));
%                 app.RTSimulator.setPeopleRadius(str2double(fileNameSplit{6}));  
                app.RTSimulator.paths = {}; % Re-initialize MPC vector to avoid errors             
                
                % plot the loaded crowd
                app.RTSimulator.plotConfiguration();
                app.upDateCrowdTable();
            end
        end

        % Button pushed function: SavelocationsButton
        function SavelocationsButtonPushed(app, event)
            % Save the crowd position in a csv file. The name of the file
            % contains info like crowd density, crowd spatial model and
            % radius of people. If a file already has this info, we number
            % the different files. Up to 999 files with the same
            % attributes.
            switch app.CrowdModelDropDown.Value
                case 'Regular grid'
                    modelName = 'RG';
                case 'Binomial point process with repulsion'
                    modelName = 'BPP';
                case 'Poisson point process with repulsion'
                    modelName = 'PPP';
            end
            for i = 1:999
                testName = ['data\people2DPosition_' ...
                        modelName ...
                        '_density_' num2str(app.RTSimulator.peopleDensity, 4) ...
                        '_radius_' num2str(app.RTSimulator.peopleRadius, 4) ...
                        '_' num2str(i,'%03u') '.csv'];
                if ~exist(testName, 'file')
                    writematrix(app.RTSimulator.people2DPosition, testName, 'delimiter', ';');
                    app.RTSimulator.OutputMessagesTextArea.Value = ['File ' testName ' saved.'];
                break
                end
            end            
        end

        % Button pushed function: LoadlocationsButton_2
        function LoadlocationsButton_2Pushed(app, event)
            % Load the antenna positions
            % Start by opening a window to select the file for TX
            [file, path] = uigetfile('*_TX_*.csv', 'Select the emitters positions', ...
                'data\');
            if isequal(file,0) % cancel option
               app.RTSimulator.OutputMessagesTextArea.Value = 'User selected Cancel.';
            else % file selected
                fileName = fullfile(path, file);
%                 app.RTSimulator.OutputMessagesTextArea.Value = ['User selected ', fileName];
                % get and set the antenna positions from the file
                pos = load(fileName);
                app.RTSimulator.setTXLocations(size(pos, 1), pos);
                % find the information about the antennas from the file name
%                 fileNameSplit = strsplit(fileName, {'_', '.csv'});
%                 app.RTSimulator.setFrequency(str2double(fileNameSplit{3}));
%                 app.RTSimulator.setPTX(str2double(fileNameSplit{5}));      
                
                
                % plot the loaded crowd
                app.RTSimulator.plotConfiguration();
%                 app.upDateTX();
            end  
            % Then the same for RX
            [file, path] = uigetfile('*_RX_*.csv', 'Select the receivers positions', ...
                'data\');
            if isequal(file,0) % cancel option
               app.RTSimulator.OutputMessagesTextArea.Value = 'User selected Cancel.';
            else % file selected
                fileName = fullfile(path, file);
                app.RTSimulator.OutputMessagesTextArea.Value = ['User selected ', fileName];
                % get and set the antenna positions from the file
                pos = load(fileName);
                app.RTSimulator.setRXLocations(size(pos, 1), pos);  
                
                % plot the loaded crowd
                app.RTSimulator.plotConfiguration();
                app.upDateTX();
            end         
        end

        % Button pushed function: SavelocationsButton_2
        function SavelocationsButton_2Pushed(app, event)
            % Save the crowd position in a csv file. The name of the file
            % contains info like crowd density, crowd spatial model and
            % radius of people. If a file already has this info, we number
            % the different files. Up to 999 files with the same
            % attributes.
            for i = 1:999
                testName = ['data\antenna2DPosition_' ...
                        '_freq_' num2str(app.RTSimulator.f, 11) ...
                        '_PTX_' num2str(app.RTSimulator.PTX, 4) ...
                        '_NTX_' num2str(app.NTXSpinner.Value, 4) ...
                        '_NRX_' num2str(app.NRXSpinner.Value, 4) ...
                        '_TX_' num2str(i,'%03u') '.csv'];
                if ~exist(testName, 'file')
                    writematrix(app.RTSimulator.emitters2DPosition, testName, 'delimiter', ';');
                    app.RTSimulator.OutputMessagesTextArea.Value = ['File ' testName ' saved.'];
                break
                end
            end  
            for i = 1:999
                testName = ['data\antenna2DPosition_' ...
                        '_freq_' num2str(app.RTSimulator.f, 11) ...
                        '_PTX_' num2str(app.RTSimulator.PTX, 4) ...
                        '_NTX_' num2str(app.NTXSpinner.Value, 4) ...
                        '_NRX_' num2str(app.NRXSpinner.Value, 4) ...
                        '_RX_' num2str(i,'%03u') '.csv'];
                if ~exist(testName, 'file')
                    writematrix(app.RTSimulator.receivers2DPosition, testName, 'delimiter', ';');
                    app.RTSimulator.OutputMessagesTextArea.Value = ['File ' testName ' saved.'];
                break
                end
            end            
        end

        % Button pushed function: AddemitterButton
        function AddemitterButtonPushed(app, event)
            newPosition = [app.RTSimulator.xLength*rand(1)+app.RTSimulator.windowLimits(1), ...% shift to be in the window
                        app.RTSimulator.yLength*rand(1)+app.RTSimulator.windowLimits(3)];
            app.RTSimulator.setTXLocations(size(app.RTSimulator.emitters2DPosition, 2)+1, ...
                [app.RTSimulator.emitters2DPosition; newPosition]);
            app.AntennaTree.SelectedNodes = app.EmittersNode.Children(end);
            app.xSpinner.Value = app.AntennaTree.SelectedNodes.NodeData(1);
            app.ySpinner.Value = app.AntennaTree.SelectedNodes.NodeData(2);
            app.plotConfiguration();          
        end

        % Button pushed function: AddreceiverButton
        function AddreceiverButtonPushed(app, event)
            newPosition = [app.RTSimulator.xLength*rand(1)+app.RTSimulator.windowLimits(1), ...% shift to be in the window
                        app.RTSimulator.yLength*rand(1)+app.RTSimulator.windowLimits(3)];
            app.RTSimulator.setRXLocations(size(app.RTSimulator.receivers2DPosition, 2)+1, ...
                [app.RTSimulator.receivers2DPosition; newPosition]);  
            app.AntennaTree.SelectedNodes = app.ReceiversNode.Children(end);
            app.xSpinner.Value = app.AntennaTree.SelectedNodes.NodeData(1);
            app.ySpinner.Value = app.AntennaTree.SelectedNodes.NodeData(2);
            app.plotConfiguration();          
        end

        % Button pushed function: RemovethisantennaButton
        function RemovethisantennaButtonPushed(app, event)
            if ~isempty(app.AntennaTree.SelectedNodes)
                text = strsplit(app.AntennaTree.SelectedNodes.Text, ' ');
                type = text{1};
                if strcmp(type, 'TX')
                    app.NTXSpinner.Value = app.NTXSpinner.Value-1;
                    ind = find(eq(app.AntennaTree.SelectedNodes, app.EmittersNode.Children));
                    app.RTSimulator.setTXLocations(app.NTXSpinner.Value, ...
                        app.RTSimulator.emitters2DPosition(1:end ~= ind, :));
                    if ind > 1
                        app.AntennaTree.SelectedNodes = app.EmittersNode.Children(ind-1);
                        app.xSpinner.Value = app.AntennaTree.SelectedNodes.NodeData(1);
                        app.ySpinner.Value = app.AntennaTree.SelectedNodes.NodeData(2);
                    end
                elseif strcmp(type, 'RX')
                    % Remove from the tree
                    app.NRXSpinner.Value = app.NRXSpinner.Value-1;
                    ind = find(eq(app.AntennaTree.SelectedNodes, app.ReceiversNode.Children));
                    app.RTSimulator.setRXLocations(app.NRXSpinner.Value, ...
                        app.RTSimulator.receivers2DPosition(1:end ~= ind, :));
                    if ind > 1
                        app.AntennaTree.SelectedNodes = app.ReceiversNode.Children(ind-1);
                        app.xSpinner.Value = app.AntennaTree.SelectedNodes.NodeData(1);
                        app.ySpinner.Value = app.AntennaTree.SelectedNodes.NodeData(2);
                    end
                end
                app.plotConfiguration();
            end             
        end

        % Button pushed function: AddapersonButton
        function AddapersonButtonPushed(app, event)
            newPosFound = 0;
            numit = 1;
            currentPos = app.RTSimulator.people2DPosition;
            while ~newPosFound & numit < 20
                % Generate a new position
                newPosition = [rand(1)*app.RTSimulator.xLength+app.RTSimulator.windowLimits(1), ...
                    rand(1)*app.RTSimulator.yLength+app.RTSimulator.windowLimits(3)];
                % Test this new position and check if the new person
                % does not intersect anyone.
                newPosFound = app.RTSimulator.addNewPersonWithoutCrash(currentPos, newPosition);
                if newPosFound
                    app.RTSimulator.setPeoplePosition([currentPos; newPosition]);
                    app.upDateCrowdTable();
                    app.RTSimulator.setNbPersons(app.RTSimulator.nbPersons+1);
                    app.PeopleTree.SelectedNodes = app.PeopleTree.Children(end);
                    app.xSpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(1);
                    app.ySpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(2);
                end
                numit = numit + 1;
            end  
            app.plotConfiguration();          
        end

        % Button pushed function: RemovethispersonButton
        function RemovethispersonButtonPushed(app, event)
            if ~isempty(app.PeopleTree.SelectedNodes)
                % Remove from the position vector and the tree
                ind = find(eq(app.PeopleTree.SelectedNodes, app.PeopleTree.Children));
                app.RTSimulator.setPeoplePosition(app.RTSimulator.people2DPosition(1:end ~= ind, :));
                app.upDateCrowdTable();
                app.RTSimulator.setNbPersons(app.RTSimulator.nbPersons-1);
                % Plot and select the previous node
                if ind > 1
                    app.PeopleTree.SelectedNodes = app.PeopleTree.Children(ind-1);
                    app.xSpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(1);
                    app.ySpinner_2.Value = app.PeopleTree.SelectedNodes.NodeData(2);
                end
                app.plotConfiguration();
            end              
        end

        % Selection changed function: WallTree
        function WallTreeSelectionChanged(app, event)
            app.updateWallSpinnersFromNodeData();
            app.plotConfiguration();
        end

        % Value changed function: xw1
        function xw1ValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Extremity1(1) = app.xw1.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Translate the wall
                app.RTSimulator.walls(ind).Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1)...
                    - app.RTSimulator.walls(ind).Extremity1(1) + app.xw1.Value;
                app.RTSimulator.walls(ind).Extremity1(1) = app.xw1.Value;
                app.xw2.Value = app.RTSimulator.walls(ind).Extremity2(1) ;
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1);
                app.plotConfiguration();
            end
        end

        % Value changed function: yw1
        function yw1ValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Extremity1(2) = app.yw1.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Translate the wall
                app.RTSimulator.walls(ind).Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2)...
                    - app.RTSimulator.walls(ind).Extremity1(2) + app.yw1.Value;
                app.RTSimulator.walls(ind).Extremity1(2) = app.yw1.Value;
                app.yw2.Value = app.RTSimulator.walls(ind).Extremity2(2);
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2);
                app.plotConfiguration();
            end 
        end

        % Value changed function: xw2
        function xw2ValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.xw2.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Translate the wall
                app.RTSimulator.walls(ind).Extremity1(1) = app.RTSimulator.walls(ind).Extremity1(1)...
                    - app.RTSimulator.walls(ind).Extremity2(1) + app.xw2.Value;
                app.RTSimulator.walls(ind).Extremity2(1) = app.xw2.Value;
                app.xw1.Value = app.RTSimulator.walls(ind).Extremity1(1);
                app.WallTree.SelectedNodes.NodeData.Extremity1(1) = app.RTSimulator.walls(ind).Extremity1(1);
                app.plotConfiguration();
            end 
        end

        % Value changed function: yw2
        function yw2ValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.yw2.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Translate the wall
                app.RTSimulator.walls(ind).Extremity1(2) = app.RTSimulator.walls(ind).Extremity1(2)...
                    - app.RTSimulator.walls(ind).Extremity2(2) + app.yw2.Value;
                app.RTSimulator.walls(ind).Extremity2(2) = app.yw2.Value;
                app.yw1.Value = app.RTSimulator.walls(ind).Extremity1(2);
                app.WallTree.SelectedNodes.NodeData.Extremity1(2) = app.RTSimulator.walls(ind).Extremity1(2);
                app.plotConfiguration();
            end
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Width = app.WidthSpinner.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Change the width
                app.RTSimulator.walls(ind).Width = app.WidthSpinner.Value;
                app.plotConfiguration();
            end 
        end

        % Value changed function: LengthSpinner
        function LengthSpinnerValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.Length = app.LengthSpinner.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Extend the wall
                app.RTSimulator.walls(ind).Extremity2(1) = app.RTSimulator.walls(ind).Extremity1(1)...
                    + app.LengthSpinner.Value*app.RTSimulator.walls(ind).nx;
                app.RTSimulator.walls(ind).Extremity2(2) = app.RTSimulator.walls(ind).Extremity1(2)...
                    + app.LengthSpinner.Value*app.RTSimulator.walls(ind).ny;
                app.RTSimulator.walls(ind).Length = app.LengthSpinner.Value;
                
                app.xw2.Value = app.RTSimulator.walls(ind).Extremity2(1) ;
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1);
                app.yw2.Value = app.RTSimulator.walls(ind).Extremity2(2);
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2);
                
                app.plotConfiguration();
            end 
        end

        % Value changed function: nx
        function nxValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.nx = app.nx.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Update nx and ny
                app.RTSimulator.walls(ind).ny = sign(app.RTSimulator.walls(ind).ny) * ...
                    sqrt(1-app.nx.Value^2);
                app.WallTree.SelectedNodes.NodeData.ny = app.RTSimulator.walls(ind).ny;
                app.ny.Value = app.RTSimulator.walls(ind).ny;
                app.RTSimulator.walls(ind).nx = app.nx.Value;
                % Update the angle theta_n
                if app.RTSimulator.walls(ind).ny >= 0
                    app.RTSimulator.walls(ind).thetan = acos(app.RTSimulator.walls(ind).nx);
                else
                    app.RTSimulator.walls(ind).thetan = pi/2+acos(app.RTSimulator.walls(ind).nx);
                end
                app.WallTree.SelectedNodes.NodeData.thetan = app.RTSimulator.walls(ind).thetan;
                app.thetan.Value = app.RTSimulator.walls(ind).thetan*180/pi;
                
                % Update extremity2
                app.RTSimulator.walls(ind).Extremity2 = app.RTSimulator.walls(ind).Extremity1 + ...
                    [app.RTSimulator.walls(ind).nx, app.RTSimulator.walls(ind).ny] * ...
                    app.RTSimulator.walls(ind).Length;
                app.xw2.Value = app.RTSimulator.walls(ind).Extremity2(1);
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1);
                app.yw2.Value = app.RTSimulator.walls(ind).Extremity2(2);
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2);
                                
                
                app.plotConfiguration();
            end
        end

        % Value changed function: ny
        function nyValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.ny = app.ny.Value;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Update nx and ny
                app.RTSimulator.walls(ind).nx = sign(app.RTSimulator.walls(ind).nx) * ...
                    sqrt(1-app.ny.Value^2);
                app.WallTree.SelectedNodes.NodeData.nx = app.RTSimulator.walls(ind).nx;
                app.nx.Value = app.RTSimulator.walls(ind).nx;
                app.RTSimulator.walls(ind).ny = app.ny.Value;
                % Update the angle theta_n
                if app.RTSimulator.walls(ind).ny >= 0
                    app.RTSimulator.walls(ind).thetan = acos(app.RTSimulator.walls(ind).nx);
                else
                    app.RTSimulator.walls(ind).thetan = pi/2+acos(app.RTSimulator.walls(ind).nx);
                end
                app.WallTree.SelectedNodes.NodeData.thetan = app.RTSimulator.walls(ind).thetan;
                app.thetan.Value = app.RTSimulator.walls(ind).thetan*180/pi;
                
                % Update extremity2
                app.RTSimulator.walls(ind).Extremity2 = app.RTSimulator.walls(ind).Extremity1 + ...
                    [app.RTSimulator.walls(ind).nx, app.RTSimulator.walls(ind).ny] * ...
                    app.RTSimulator.walls(ind).Length;
                app.xw2.Value = app.RTSimulator.walls(ind).Extremity2(1);
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1);
                app.yw2.Value = app.RTSimulator.walls(ind).Extremity2(2);
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2);
                       
                app.plotConfiguration();
            end  
        end

        % Value changed function: thetan
        function thetanValueChanged(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                app.WallTree.SelectedNodes.NodeData.thetan = app.thetan.Value*pi/180;
                text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
                ind = str2double(text{2});
                % Update nx and ny
                app.RTSimulator.walls(ind).nx = cos(app.thetan.Value*pi/180);
                app.RTSimulator.walls(ind).ny = sin(app.thetan.Value*pi/180);
                app.RTSimulator.walls(ind).thetan = app.thetan.Value*pi/180;
                app.WallTree.SelectedNodes.NodeData.nx = app.RTSimulator.walls(ind).nx;
                app.WallTree.SelectedNodes.NodeData.ny = app.RTSimulator.walls(ind).ny;
                app.nx.Value = app.RTSimulator.walls(ind).nx;
                app.ny.Value = app.RTSimulator.walls(ind).ny;
                
                % Update extremity2
                app.RTSimulator.walls(ind).Extremity2 = app.RTSimulator.walls(ind).Extremity1 + ...
                    [app.RTSimulator.walls(ind).nx, app.RTSimulator.walls(ind).ny] * ...
                    app.RTSimulator.walls(ind).Length;
                app.xw2.Value = app.RTSimulator.walls(ind).Extremity2(1);
                app.WallTree.SelectedNodes.NodeData.Extremity2(1) = app.RTSimulator.walls(ind).Extremity2(1) ;
                app.yw2.Value = app.RTSimulator.walls(ind).Extremity2(2);
                app.WallTree.SelectedNodes.NodeData.Extremity2(2) = app.RTSimulator.walls(ind).Extremity2(2);
                       
                app.plotConfiguration();
            end
        end

        % Value changed function: PermittivitySpinner
        function PermittivitySpinnerValueChanged(app, event)
            text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
            ind = str2double(text{2});
            app.WallTree.SelectedNodes.NodeData.Permittivity = app.PermittivitySpinner.Value;
            app.RTSimulator.walls(ind).Permittivity = app.PermittivitySpinner.Value;
            [gamma, Z] = app.RTSimulator.setElecConst(app.RTSimulator.walls(ind));
            app.RTSimulator.walls(ind).gamma = gamma;
            app.RTSimulator.walls(ind).Z = Z;
        end

        % Value changed function: ConductivitySpinner
        function ConductivitySpinnerValueChanged(app, event)
            text = strsplit(app.WallTree.SelectedNodes.Text, ' ');
            ind = str2double(text{2});
            app.WallTree.SelectedNodes.NodeData.Conductivity = app.ConductivitySpinner.Value;
            app.RTSimulator.walls(ind).Conductivity = app.ConductivitySpinner.Value;
            [gamma, Z] = app.RTSimulator.setElecConst(app.RTSimulator.walls(ind));
            app.RTSimulator.walls(ind).gamma = gamma;
            app.RTSimulator.walls(ind).Z = Z;    
        end

        % Button pushed function: AddWallButton
        function AddWallButtonPushed(app, event)
            newWall.Extremity1 = [app.xw1.Value, app.yw1.Value];
            newWall.Extremity2 = [app.xw2.Value, app.yw2.Value];
            newWall.Width = app.WidthSpinner.Value;
            newWall.Length = app.LengthSpinner.Value;
            newWall.nx = app.nx.Value;
            newWall.ny = app.ny.Value;
            newWall.thetan = app.thetan.Value*pi/180;
            newWall.Permittivity = app.PermittivitySpinner.Value;
            newWall.Conductivity = app.ConductivitySpinner.Value;
            newWall.Permeability = app.PermeabilitySpinner.Value;
            [gamma, Z] = app.RTSimulator.setElecConst(newWall);
            newWall.gamma = gamma;
            newWall.Z = Z;
            app.RTSimulator.setWalls([app.RTSimulator.walls; newWall]);
            app.upDateWallTable();
            app.WallTree.SelectedNodes = app.WallTree.Children(end);
            %app.updateWallSpinnersFromNodeData;
            app.plotConfiguration();              
        end

        % Button pushed function: RemoveWallButton
        function RemoveWallButtonPushed(app, event)
            if ~isempty(app.WallTree.SelectedNodes)
                % Remove from the position vector and the tree
                ind = find(eq(app.WallTree.SelectedNodes, app.WallTree.Children));
                app.RTSimulator.setWalls(app.RTSimulator.walls(setdiff(1:numel(app.RTSimulator.walls), ind)));
                app.upDateWallTable();
                % Plot and select the previous node
                if ind > 1
                    app.WallTree.SelectedNodes = app.WallTree.Children(ind-1);
                    app.updateWallSpinnersFromNodeData();
                end
                app.plotConfiguration();
            end 
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            switch app.MaterialDropDown.Value
                %2.45GHz
                case 'Brick'
                    epsr = 4.6;
                    sig = 0.02;
                    mur = 1;
                %2.45GHz
                case 'Concrete'
                    epsr = 5.0;
                    sig = 0.014;
                    mur = 1;
                %868.3MHz
                case 'Plastic'
                    epsr = 2.25;
                    sig = 0.003;
                    mur = 1;
            end
            app.PermittivitySpinner.Value = epsr;
            app.ConductivitySpinner.Value = sig;
            app.PermeabilitySpinner.Value = mur;
            if ~isempty(app.WallTree.SelectedNodes)
                ind = find(eq(app.WallTree.SelectedNodes, app.WallTree.Children));
                app.WallTree.SelectedNodes.NodeData.Permittivity = epsr;
                app.RTSimulator.walls(ind).Permittivity = epsr;  
                app.WallTree.SelectedNodes.NodeData.Conductivity = sig;
                app.RTSimulator.walls(ind).Conductivity = sig;   
                app.WallTree.SelectedNodes.NodeData.Permeability = mur;
                app.RTSimulator.walls(ind).Permeability = mur;   
                [gamma, Z] = app.RTSimulator.setElecConst(app.RTSimulator.walls(ind));
                app.RTSimulator.walls(ind).gamma = gamma;
                app.RTSimulator.walls(ind).Z = Z;                
            end
        end

        % Button pushed function: LoadWallLocations
        function LoadWallLocationsButtonPushed(app, event)
            % Load the crowd positions
            % Start by opening a window to select the file
            app.RTSimulator.OutputMessagesTextArea.Value = 'Not finished.';
            [file, path] = uigetfile('*walls*.csv', 'Select a file', ...
                'data\');
            if isequal(file,0) % cancel option
               app.RTSimulator.OutputMessagesTextArea.Value = 'User selected Cancel.';
            else % file selected
                fileName = fullfile(path, file);
                app.RTSimulator.OutputMessagesTextArea.Value = ['User selected ', fileName];
                % get and set the people positions from the file
                wallsRough = load(fileName);
                
                walls = struct();
                for i=1:size(wallsRough, 1)
                    walls(i).Extremity1 = [wallsRough(i,1), wallsRough(i,2)];
                    walls(i).Extremity2 = [wallsRough(i,3), wallsRough(i,4)];
                    walls(i).Width = wallsRough(i,5);
                    walls(i).Length = wallsRough(i,6);
                    walls(i).nx = wallsRough(i,7);
                    walls(i).ny = wallsRough(i,8);
                    walls(i).thetan = wallsRough(i,9);
                    walls(i).Permittivity = wallsRough(i,10);
                    walls(i).Conductivity = wallsRough(i,11);
                    walls(i).Permeability = wallsRough(i,12);
                    walls(i).gamma = wallsRough(i,13);
                    walls(i).Z = wallsRough(i,14);
                end
                
                app.RTSimulator.setWalls(walls);
                
                % plot the loaded crowd
                app.RTSimulator.plotConfiguration();
            end             
        end

        % Button pushed function: SaveWallLocations
        function SaveWallLocationsButtonPushed(app, event)
            % Save the wall position in a csv file. The name of the file
            % contains the number of walls. If a file already has this info, we number
            % the different files. Up to 999 files with the same
            % attributes.
            app.RTSimulator.OutputMessagesTextArea.Value = 'Not finished.';
            [file, path, ~] = uiputfile('.csv', 'Choose a name. walls_ must be the beginning.', 'data\');
            if ~startsWith(file,'walls')
                name = [path 'walls_' file];
            else
                name = [path file];
            end
            
            matrix = zeros(app.RTSimulator.nbWalls, 11);
            for i=1:app.RTSimulator.nbWalls
                matrix(i, 1:2) = app.RTSimulator.walls(i).Extremity1;
                matrix(i, 3:4) = app.RTSimulator.walls(i).Extremity2;
                matrix(i, 5) = app.RTSimulator.walls(i).Width;
                matrix(i, 6) = app.RTSimulator.walls(i).Length;
                matrix(i, 7) = app.RTSimulator.walls(i).nx;
                matrix(i, 8) = app.RTSimulator.walls(i).ny;
                matrix(i, 9) = app.RTSimulator.walls(i).thetan;
                matrix(i, 10) = app.RTSimulator.walls(i).Permittivity;
                matrix(i, 11) = app.RTSimulator.walls(i).Conductivity;
                matrix(i, 12) = app.RTSimulator.walls(i).Permeability;
                matrix(i, 13) = app.RTSimulator.walls(i).gamma;
                matrix(i, 14) = app.RTSimulator.walls(i).Z;
            end
            
            writematrix(matrix, name, 'delimiter', ';');
            app.RTSimulator.OutputMessagesTextArea.Value = ['File ' name ' saved.'];                      
        end

        % Value changing function: PermittivitySpinner
        function PermittivitySpinnerValueChanging(app, event)
            app.WallTree.SelectedNodes.NodeData.Permeability = app.PermeabilitySpinner.Value;
            app.RTSimulator.walls(ind).Permeability = app.PermeabilitySpinner.Value;
            [gamma, Z] = app.RTSimulator.setElecConst(app.RTSimulator.walls(ind));
            app.RTSimulator.walls(ind).gamma = gamma;
            app.RTSimulator.walls(ind).Z = Z;          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatorParametersUIFigure = uifigure('Visible', 'off');
            app.SimulatorParametersUIFigure.Name = 'Simulator Parameters';
            app.SimulatorParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulatorParametersUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SimulatorParametersUIFigure);
            app.AntennaTab = uitab(app.TabGroup);
            app.AntennaTab.Title = 'Antenna';
            app.NumberofantennasandcarrierfrequencyPanel = uipanel(app.AntennaTab);
            app.NumberofantennasandcarrierfrequencyPanel.Title = 'Number of antennas and carrier frequency';
            app.FrequencyDropDownLabel = uilabel(app.NumberofantennasandcarrierfrequencyPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.NumberofantennasandcarrierfrequencyPanel);
            app.FrequencyDropDown.Items = {'2.45 GHz', '5 GHz', '28 GHz', '60 GHz'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '2.45 GHz';
            app.PTXEditFieldLabel = uilabel(app.NumberofantennasandcarrierfrequencyPanel);
            app.PTXEditFieldLabel.Text = 'PTX';
            app.PTXEditField = uieditfield(app.NumberofantennasandcarrierfrequencyPanel, 'numeric');
            app.PTXEditField.ValueDisplayFormat = '%11.4g dBm';
            app.PTXEditField.ValueChangedFcn = createCallbackFcn(app, @PTXEditFieldValueChanged, true);
            app.NTXSpinnerLabel = uilabel(app.NumberofantennasandcarrierfrequencyPanel);
            app.NTXSpinnerLabel.Text = 'NTX';
            app.NTXSpinner = uispinner(app.NumberofantennasandcarrierfrequencyPanel);
            app.NTXSpinner.Limits = [0 Inf];
            app.NTXSpinner.ValueDisplayFormat = '%d';
            app.NTXSpinner.ValueChangedFcn = createCallbackFcn(app, @NTXSpinnerValueChanged, true);
            app.NRXSpinnerLabel = uilabel(app.NumberofantennasandcarrierfrequencyPanel);
            app.NRXSpinnerLabel.Text = 'NRX';
            app.NRXSpinner = uispinner(app.NumberofantennasandcarrierfrequencyPanel);
            app.NRXSpinner.Limits = [0 Inf];
            app.NRXSpinner.ValueDisplayFormat = '%d';
            app.NRXSpinner.ValueChangedFcn = createCallbackFcn(app, @NRXSpinnerValueChanged, true);
            app.AntennaspropertiesPanel = uipanel(app.AntennaTab);
            app.AntennaspropertiesPanel.Title = 'Antennas properties';
            app.AntennaTree = uitree(app.AntennaspropertiesPanel);
            app.AntennaTree.SelectionChangedFcn = createCallbackFcn(app, @AntennaTreeSelectionChanged, true);
            app.EmittersNode = uitreenode(app.AntennaTree);
            app.EmittersNode.Text = 'Emitters';
            app.ReceiversNode = uitreenode(app.AntennaTree);
            app.ReceiversNode.Text = 'Receivers';
            app.xSpinnerLabel = uilabel(app.AntennaspropertiesPanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.AntennaspropertiesPanel);
            app.xSpinner.ValueDisplayFormat = '%11.2f';
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged, true);
            app.ySpinnerLabel = uilabel(app.AntennaspropertiesPanel);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.AntennaspropertiesPanel);
            app.ySpinner.ValueDisplayFormat = '%11.2f';
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.LoadlocationsButton_2 = uibutton(app.AntennaspropertiesPanel, 'push');
            app.LoadlocationsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadlocationsButton_2Pushed, true);
            app.LoadlocationsButton_2.Text = 'Load locations';
            app.SavelocationsButton_2 = uibutton(app.AntennaspropertiesPanel, 'push');
            app.SavelocationsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavelocationsButton_2Pushed, true);
            app.SavelocationsButton_2.Text = 'Save locations';
            app.AddemitterButton = uibutton(app.AntennaspropertiesPanel, 'push');
            app.AddemitterButton.ButtonPushedFcn = createCallbackFcn(app, @AddemitterButtonPushed, true);
            app.AddemitterButton.Text = 'Add emitter';
            app.AddreceiverButton = uibutton(app.AntennaspropertiesPanel, 'push');
            app.AddreceiverButton.ButtonPushedFcn = createCallbackFcn(app, @AddreceiverButtonPushed, true);
            app.AddreceiverButton.Text = 'Add receiver';
            app.RemovethisantennaButton = uibutton(app.AntennaspropertiesPanel, 'push');
            app.RemovethisantennaButton.ButtonPushedFcn = createCallbackFcn(app, @RemovethisantennaButtonPushed, true);
            app.RemovethisantennaButton.Text = 'Remove this antenna';
            app.CrowdTab = uitab(app.TabGroup);
            app.CrowdTab.Title = 'Crowd';
            app.PeopleradiusEditFieldLabel = uilabel(app.CrowdTab);
            app.PeopleradiusEditFieldLabel.Text = 'People radius';
            app.PeopleradiusEditField = uieditfield(app.CrowdTab, 'numeric');
            app.PeopleradiusEditField.ValueDisplayFormat = '%11.4g m';
            app.PeopleradiusEditField.ValueChangedFcn = createCallbackFcn(app, @PeopleradiusEditFieldValueChanged, true);
            app.PeopledensityEditFieldLabel = uilabel(app.CrowdTab);
            app.PeopledensityEditFieldLabel.Text = 'People density';
            app.PeopledensityEditField = uieditfield(app.CrowdTab, 'numeric');
            app.PeopledensityEditField.ValueDisplayFormat = '%11.4g persons/m²';
            app.PeopledensityEditField.ValueChangedFcn = createCallbackFcn(app, @PeopledensityEditFieldValueChanged, true);
            app.PeoplepositionsPanel = uipanel(app.CrowdTab);
            app.PeoplepositionsPanel.Title = 'People positions';
            app.PeopleTree = uitree(app.PeoplepositionsPanel);
            app.PeopleTree.SelectionChangedFcn = createCallbackFcn(app, @PeopleTreeSelectionChanged, true);
            app.xSpinner_2Label = uilabel(app.PeoplepositionsPanel);
            app.xSpinner_2Label.Text = 'x';
            app.xSpinner_2 = uispinner(app.PeoplepositionsPanel);
            app.xSpinner_2.ValueDisplayFormat = '%11.2f';
            app.xSpinner_2.ValueChangedFcn = createCallbackFcn(app, @xSpinner_2ValueChanged, true);
            app.ySpinner_2Label = uilabel(app.PeoplepositionsPanel);
            app.ySpinner_2Label.Text = 'y';
            app.ySpinner_2 = uispinner(app.PeoplepositionsPanel);
            app.ySpinner_2.ValueDisplayFormat = '%11.2f';
            app.ySpinner_2.ValueChangedFcn = createCallbackFcn(app, @ySpinner_2ValueChanged, true);
            app.LoadlocationsButton = uibutton(app.PeoplepositionsPanel, 'push');
            app.LoadlocationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlocationsButtonPushed, true);
            app.LoadlocationsButton.Text = 'Load locations';
            app.SavelocationsButton = uibutton(app.PeoplepositionsPanel, 'push');
            app.SavelocationsButton.ButtonPushedFcn = createCallbackFcn(app, @SavelocationsButtonPushed, true);
            app.SavelocationsButton.Text = 'Save locations';
            app.AddapersonButton = uibutton(app.PeoplepositionsPanel, 'push');
            app.AddapersonButton.ButtonPushedFcn = createCallbackFcn(app, @AddapersonButtonPushed, true);
            app.AddapersonButton.Text = 'Add a person';
            app.RemovethispersonButton = uibutton(app.PeoplepositionsPanel, 'push');
            app.RemovethispersonButton.ButtonPushedFcn = createCallbackFcn(app, @RemovethispersonButtonPushed, true);
            app.RemovethispersonButton.Text = 'Remove this person';
            app.NbpersonsEditFieldLabel = uilabel(app.CrowdTab);
            app.NbpersonsEditFieldLabel.Text = 'Nb persons';
            app.NbpersonsEditField = uieditfield(app.CrowdTab, 'numeric');
            app.NbpersonsEditField.ValueDisplayFormat = '%11.4g persons';
            app.NbpersonsEditField.ValueChangedFcn = createCallbackFcn(app, @NbpersonsEditFieldValueChanged, true);
            app.CrowdModelDropDownLabel = uilabel(app.CrowdTab);
            app.CrowdModelDropDownLabel.Text = 'Crowd Model';
            app.CrowdModelDropDown = uidropdown(app.CrowdTab);
            app.CrowdModelDropDown.Items = {'Regular grid', 'Binomial point process with repulsion', 'Poisson point process with repulsion'};
            app.CrowdModelDropDown.Value = 'Binomial point process with repulsion';
            app.PropagationTab = uitab(app.TabGroup);
            app.PropagationTab.Title = 'Propagation';
            app.PropagationlawsPanel = uipanel(app.PropagationTab);
            app.PropagationlawsPanel.Title = 'Propagation laws';
            app.DirectpathButtonGroup = uibuttongroup(app.PropagationlawsPanel);
            app.DirectpathButtonGroup.Title = 'Direct path';
            app.AllowDirectPath = uiradiobutton(app.DirectpathButtonGroup);
            app.AllowDirectPath.Text = 'Allow';
            app.AllowDirectPath.Value = true;
            app.CancelDirectPath = uiradiobutton(app.DirectpathButtonGroup);
            app.CancelDirectPath.Text = 'Cancel';
            app.PathlossexponentEditFieldLabel = uilabel(app.PropagationlawsPanel);
            app.PathlossexponentEditFieldLabel.Text = 'Path loss exponent';
            app.PathlossexponentEditField = uieditfield(app.PropagationlawsPanel, 'numeric');
            app.PathlossexponentEditField.ValueChangedFcn = createCallbackFcn(app, @PathlossexponentEditFieldValueChanged, true);
            app.PathlossexponentEditField.Value = 2;
            app.ReflectiononpeopleButtonGroup = uibuttongroup(app.PropagationlawsPanel);
            app.ReflectiononpeopleButtonGroup.Title = 'Reflection on people';
            app.FriislawwithPLE = uiradiobutton(app.ReflectiononpeopleButtonGroup);
            app.FriislawwithPLE.Text = 'Friis law with PLE';
            app.Bistaticradarequation = uiradiobutton(app.ReflectiononpeopleButtonGroup);
            app.Bistaticradarequation.Text = 'Bistatic radar equation';
            app.Bistaticradarequation.Value = true;
            app.WindowTab = uitab(app.TabGroup);
            app.WindowTab.Title = 'Window';
            app.CrowdlimitsPanel = uipanel(app.WindowTab);
            app.CrowdlimitsPanel.Title = 'Crowd limits';
            app.ApplychangesButton = uibutton(app.CrowdlimitsPanel, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.xminSpinnerLabel = uilabel(app.CrowdlimitsPanel);
            app.xminSpinnerLabel.Text = 'x min';
            app.xminSpinner = uispinner(app.CrowdlimitsPanel);
            app.xminSpinner.ValueDisplayFormat = '%11.2f';
            app.xminSpinner.Value = 1;
            app.xmaxSpinnerLabel = uilabel(app.CrowdlimitsPanel);
            app.xmaxSpinnerLabel.Text = 'x max';
            app.xmaxSpinner = uispinner(app.CrowdlimitsPanel);
            app.xmaxSpinner.ValueDisplayFormat = '%11.2f';
            app.xmaxSpinner.Value = 19;
            app.yminSpinnerLabel = uilabel(app.CrowdlimitsPanel);
            app.yminSpinnerLabel.Text = 'y min';
            app.yminSpinner = uispinner(app.CrowdlimitsPanel);
            app.yminSpinner.ValueDisplayFormat = '%11.2f';
            app.yminSpinner.Value = 1;
            app.ymaxSpinnerLabel = uilabel(app.CrowdlimitsPanel);
            app.ymaxSpinnerLabel.Text = 'y max';
            app.ymaxSpinner = uispinner(app.CrowdlimitsPanel);
            app.ymaxSpinner.ValueDisplayFormat = '%11.2f';
            app.ymaxSpinner.Value = 19;
            app.OFDMpropertiesTab = uitab(app.TabGroup);
            app.OFDMpropertiesTab.Title = 'OFDM properties';
            app.ModulationDropDownLabel = uilabel(app.OFDMpropertiesTab);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.OFDMpropertiesTab);
            app.ModulationDropDown.Items = {'bpsk', 'qpsk', '2pam', '4pam', '8pam', '16qam', '64qam', '256qam'};
            app.ModulationDropDown.Value = 'qpsk';
            app.BandwidthSpinnerLabel = uilabel(app.OFDMpropertiesTab);
            app.BandwidthSpinnerLabel.Text = 'Bandwidth';
            app.BandwidthSpinner = uispinner(app.OFDMpropertiesTab);
            app.BandwidthSpinner.Step = 10;
            app.BandwidthSpinner.Limits = [10 1000];
            app.BandwidthSpinner.ValueDisplayFormat = '%11.4g MHz';
            app.BandwidthSpinner.Value = 160;
            app.NumberofsubcarriersEditFieldLabel = uilabel(app.OFDMpropertiesTab);
            app.NumberofsubcarriersEditFieldLabel.Text = 'Number of subcarriers';
            app.NumberofsubcarriersEditField = uieditfield(app.OFDMpropertiesTab, 'numeric');
            app.NumberofsubcarriersEditField.Limits = [0 Inf];
            app.NumberofsubcarriersEditField.Value = 1024;
            app.ChannelmaxlengthEditFieldLabel = uilabel(app.OFDMpropertiesTab);
            app.ChannelmaxlengthEditFieldLabel.Text = 'Channel max length';
            app.ChannelmaxlengthEditField = uieditfield(app.OFDMpropertiesTab, 'numeric');
            app.ChannelmaxlengthEditField.Limits = [0 Inf];
            app.ChannelmaxlengthEditField.Value = 64;
            app.OSFEditFieldLabel = uilabel(app.OFDMpropertiesTab);
            app.OSFEditFieldLabel.Text = 'OSF';
            app.OSFEditField = uieditfield(app.OFDMpropertiesTab, 'numeric');
            app.OSFEditField.Limits = [0 Inf];
            app.OSFEditField.Value = 1;
            app.WallsTab = uitab(app.TabGroup);
            app.WallsTab.Title = 'Walls';
            app.WalllistPanel = uipanel(app.WallsTab);
            app.WalllistPanel.Title = 'Wall list';
            app.WallTree = uitree(app.WalllistPanel);
            app.WallTree.SelectionChangedFcn = createCallbackFcn(app, @WallTreeSelectionChanged, true);
            app.LoadWallLocations = uibutton(app.WalllistPanel, 'push');
            app.LoadWallLocations.ButtonPushedFcn = createCallbackFcn(app, @LoadWallLocationsButtonPushed, true);
            app.LoadWallLocations.Text = 'Load locations';
            app.SaveWallLocations = uibutton(app.WalllistPanel, 'push');
            app.SaveWallLocations.ButtonPushedFcn = createCallbackFcn(app, @SaveWallLocationsButtonPushed, true);
            app.SaveWallLocations.Text = 'Save locations';
            app.AddWallButton = uibutton(app.WalllistPanel, 'push');
            app.AddWallButton.ButtonPushedFcn = createCallbackFcn(app, @AddWallButtonPushed, true);
            app.AddWallButton.Text = 'Add a wall';
            app.RemoveWallButton = uibutton(app.WalllistPanel, 'push');
            app.RemoveWallButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWallButtonPushed, true);
            app.RemoveWallButton.Text = 'Remove this wall';
            app.MaterialDropDownLabel = uilabel(app.WalllistPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.WalllistPanel);
            app.MaterialDropDown.Items = {'Brick', 'Concrete', 'Composite ex BA3', 'Plastic', 'GRP'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Brick';
            app.Extremity1Panel = uipanel(app.WallsTab);
            app.Extremity1Panel.Title = 'Extremity 1';
            app.xSpinner_3Label = uilabel(app.Extremity1Panel);
            app.xSpinner_3Label.Text = 'x';
            app.xw1 = uispinner(app.Extremity1Panel);
            app.xw1.ValueDisplayFormat = '%11.2f';
            app.xw1.ValueChangedFcn = createCallbackFcn(app, @xw1ValueChanged, true);
            app.ySpinner_3Label = uilabel(app.Extremity1Panel);
            app.ySpinner_3Label.Text = 'y';
            app.yw1 = uispinner(app.Extremity1Panel);
            app.yw1.ValueDisplayFormat = '%11.2f';
            app.yw1.ValueChangedFcn = createCallbackFcn(app, @yw1ValueChanged, true);
            app.Extremity2Panel = uipanel(app.WallsTab);
            app.Extremity2Panel.Title = 'Extremity 2';
            app.xSpinner_4Label = uilabel(app.Extremity2Panel);
            app.xSpinner_4Label.Text = 'x';
            app.xw2 = uispinner(app.Extremity2Panel);
            app.xw2.ValueDisplayFormat = '%11.2f';
            app.xw2.ValueChangedFcn = createCallbackFcn(app, @xw2ValueChanged, true);
            app.ySpinner_4Label = uilabel(app.Extremity2Panel);
            app.ySpinner_4Label.Text = 'y';
            app.yw2 = uispinner(app.Extremity2Panel);
            app.yw2.ValueDisplayFormat = '%11.2f';
            app.yw2.ValueChangedFcn = createCallbackFcn(app, @yw2ValueChanged, true);
            app.yw2.Value = 20;
            app.SteeringvectorPanel = uipanel(app.WallsTab);
            app.SteeringvectorPanel.Title = 'Steering vector';
            app.nxLabel = uilabel(app.SteeringvectorPanel);
            app.nxLabel.Text = 'nx';
            app.nx = uispinner(app.SteeringvectorPanel);
            app.nx.Step = 0.01;
            app.nx.Limits = [-1 1];
            app.nx.ValueDisplayFormat = '%11.2f';
            app.nx.ValueChangedFcn = createCallbackFcn(app, @nxValueChanged, true);
            app.nyLabel = uilabel(app.SteeringvectorPanel);
            app.nyLabel.Text = 'ny';
            app.ny = uispinner(app.SteeringvectorPanel);
            app.ny.Step = 0.01;
            app.ny.Limits = [-1 1];
            app.ny.ValueDisplayFormat = '%11.2f';
            app.ny.ValueChangedFcn = createCallbackFcn(app, @nyValueChanged, true);
            app.ny.Value = 1;
            app.AngleSpinnerLabel = uilabel(app.SteeringvectorPanel);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.thetan = uispinner(app.SteeringvectorPanel);
            app.thetan.Limits = [0 360];
            app.thetan.ValueDisplayFormat = '%11.2f°';
            app.thetan.ValueChangedFcn = createCallbackFcn(app, @thetanValueChanged, true);
            app.thetan.Value = 90;
            app.WidthPanel = uipanel(app.WallsTab);
            app.WidthPanel.Title = 'Width';
            app.WidthSpinner = uispinner(app.WidthPanel);
            app.WidthSpinner.Step = 0.1;
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.ValueDisplayFormat = '%11.2f';
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 0.5;
            app.ElectricconstantPanel = uipanel(app.WallsTab);
            app.ElectricconstantPanel.Title = 'Electric constant';
            app.PermittivitySpinner = uispinner(app.ElectricconstantPanel);
            app.PermittivitySpinner.Step = 0.05;
            app.PermittivitySpinner.ValueChangingFcn = createCallbackFcn(app, @PermittivitySpinnerValueChanging, true);
            app.PermittivitySpinner.Limits = [0 Inf];
            app.PermittivitySpinner.ValueDisplayFormat = '%11.2f';
            app.PermittivitySpinner.ValueChangedFcn = createCallbackFcn(app, @PermittivitySpinnerValueChanged, true);
            app.PermittivitySpinner.Value = 4.6;
            app.ConductivitySpinner = uispinner(app.ElectricconstantPanel);
            app.ConductivitySpinner.Step = 0.01;
            app.ConductivitySpinner.Limits = [0 Inf];
            app.ConductivitySpinner.ValueDisplayFormat = '%11.2f';
            app.ConductivitySpinner.ValueChangedFcn = createCallbackFcn(app, @ConductivitySpinnerValueChanged, true);
            app.ConductivitySpinner.Value = 0.02;
            app.RelativepermittivityLabel = uilabel(app.ElectricconstantPanel);
            app.RelativepermittivityLabel.Text = 'Relative permittivity';
            app.RelativepermeabilityLabel = uilabel(app.ElectricconstantPanel);
            app.RelativepermeabilityLabel.Text = 'Relative permeability';
            app.ConductivityLabel = uilabel(app.ElectricconstantPanel);
            app.ConductivityLabel.Text = 'Conductivity';
            app.PermeabilitySpinner = uispinner(app.ElectricconstantPanel);
            app.PermeabilitySpinner.Step = 0.01;
            app.PermeabilitySpinner.Limits = [0 Inf];
            app.PermeabilitySpinner.ValueDisplayFormat = '%11.2f';
            app.PermeabilitySpinner.Value = 1;
            app.LengthPanel = uipanel(app.WallsTab);
            app.LengthPanel.Title = 'Length';
            app.LengthSpinner = uispinner(app.LengthPanel);
            app.LengthSpinner.Step = 0.5;
            app.LengthSpinner.Limits = [0 Inf];
            app.LengthSpinner.ValueDisplayFormat = '%11.2f';
            app.LengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthSpinnerValueChanged, true);
            app.LengthSpinner.Value = 20;
            % Show the figure after all components are created
            app.SimulatorParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RTSimulatorParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatorParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatorParametersUIFigure)
        end
    end
end